classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        WindowFunctionTab   matlab.ui.container.Tab
        GridLayout          matlab.ui.container.GridLayout
        UITable             matlab.ui.control.Table
        plotWindowFunction  matlab.ui.control.UIAxes
        PianoTab            matlab.ui.container.Tab
        GuitarTab           matlab.ui.container.Tab
    end

    
    properties (Access = private)
        windowFunction WindowFunction % Description
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(5, 2);
            processingAmpVec   = [0.0  1.0  0.9  0.7  0.0];
            processingTimeVec  = [0.0  0.1  0.3  0.9  1.0];  
            app.UITable.Data = [0.0 0.0];
            app.windowFunction = WindowFunction(processingAmpVec, processingTimeVec);
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
            for i = 2 : length(processingAmpVec)
                app.UITable.Data = [app.UITable.Data; processingTimeVec(i) processingAmpVec(i)]
            end
            % Set Editable
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols); %all columns editable
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if (event.Indices(2) == 1)
                app.windowFunction.relTimeVec(event.Indices(1)) = event.NewData;
            else
                app.windowFunction.relAmpVec(event.Indices(1)) = event.NewData;
            end
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WindowFunctionTab = uitab(app.TabGroup);
            app.WindowFunctionTab.Title = 'Window Function';
            app.GridLayout = uigridlayout(app.WindowFunctionTab);
            app.plotWindowFunction = uiaxes(app.GridLayout);
            title(app.plotWindowFunction, 'Relative Window Function')
            xlabel(app.plotWindowFunction, 'Realtive Time / %')
            ylabel(app.plotWindowFunction, 'Relative Amplitude / %')
            zlabel(app.plotWindowFunction, 'Z')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Relative Time'; 'Amplitude'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PianoTab = uitab(app.TabGroup);
            app.PianoTab.Title = 'Piano';
            app.GuitarTab = uitab(app.TabGroup);
            app.GuitarTab.Title = 'Guitar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end